classdef nav_camera_terminal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        nav_camera_terminalUIFigure  matlab.ui.Figure
        BrainButton                  matlab.ui.control.Button
        CommandEditField             matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
        CommandDropDown              matlab.ui.control.DropDown
        CommandDropDownLabel         matlab.ui.control.Label
        PortEditField                matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        IPEditField                  matlab.ui.control.EditField
        IPEditFieldLabel             matlab.ui.control.Label
        repeatSwitch                 matlab.ui.control.RockerSwitch
        repeatSwitchLabel            matlab.ui.control.Label
        SendButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        LampLabel                    matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        infoTextArea                 matlab.ui.control.TextArea
        infoTextAreaLabel            matlab.ui.control.Label
        HEXTextArea                  matlab.ui.control.TextArea
        HEXTextAreaLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        nav_camera;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.Lamp.Color='r';
                

                app.nav_camera.flag_repeat=0;
                app.nav_camera.dot_h=[];

                app.nav_camera.flag_connected=0;
                

                view(app.UIAxes,[45 30]);
                set(app.UIAxes,'xlim',[-600 600],'ylim',[-600 600],'zlim',[-2000 2000]);
                axis(app.UIAxes,'vis3d')

                app.repeatSwitch.Value='Off';
                app.CommandDropDown.Enable='Off';
                app.CommandEditField.Enable='Off';
                app.SendButton.Enable='Off';
                app.repeatSwitch.Enable='Off';
                app.HEXTextArea.Enable='Off';
                app.infoTextArea.Enable='Off';
                app.UIAxes.Visible='Off';

            catch
                fprintf('error in connecting to the camera!\n');
                return;
            end;
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)

            flag_repeat=1;
            while(flag_repeat)
                val = app.CommandDropDown.Value; % Get
                param = app.CommandEditField.Value; % Get
                try
                    flag_repeat=app.nav_camera.flag_repeat;

                    switch val
                        case {'INIT','BEEP','PENA','PHRQ','PHSR','PVWR','PINIT','TSTART','TSTOP'} %no repeat!
                            app.repeatSwitch.Value='Off';
                            app.nav_camera.flag_repeat=0;
                            flag_repeat=app.nav_camera.flag_repeat;
                    end;

                    cmd=sprintf('%s %s',val,param);

                    %fprintf(app.nav_camera.tcpip,upper(val)); %read tool info (by defaults)
                    fprintf(app.nav_camera.tcpip,cmd); %read tool info (by defaults)


                    if(app.nav_camera.tcpip.BytesAvailable>0)

                        buffer=[];
                        buffer=fscanf(app.nav_camera.tcpip,'%c',app.nav_camera.tcpip.BytesAvailable);

                        %                        [str, decode_obj]=nav_camera_decode(buffer,val);
                        t0=GetSecs;
                        [str, decode_obj]=nav_camera_decode(buffer,cmd);
                        t1=GetSecs;
                        %fprintf('[%2.4f]\n',t1-t0);
                        
                        app.HEXTextArea.Value=sprintf('%s',dec2hex(buffer));
                        app.HEXTextArea.Value=sprintf('%s\n%s',app.HEXTextArea.Value{1},num2str(buffer));

                        if(iscell(str))
                            app.infoTextArea.Value=str;
                        else
                            app.infoTextArea.Value=str;
                        end;

                        if(strcmp(lower(app.CommandDropDown.Value),'bx2')&~isempty(decode_obj))
                            component=decode_obj;

                            h_idx=1;
                            for component_idx=1:length(component)
                                for frame_idx=1:length(component(component_idx).frame)
                                    for data_idx=1:length(component(component_idx).frame(frame_idx).data)
                                        for data_6d_item_idx=1:length(component(component_idx).frame(frame_idx).data(data_idx).data_6d_item)
                                            
                                            q=[ component(component_idx).frame(frame_idx).data(data_idx).data_6d_item(data_6d_item_idx).q0;...
                                                component(component_idx).frame(frame_idx).data(data_idx).data_6d_item(data_6d_item_idx).qx;...
                                                component(component_idx).frame(frame_idx).data(data_idx).data_6d_item(data_6d_item_idx).qy;...
                                                component(component_idx).frame(frame_idx).data(data_idx).data_6d_item(data_6d_item_idx).qz;];

                                            [M, euler]=nav_quat_rot(q);

                                            t=[ component(component_idx).frame(frame_idx).data(data_idx).data_6d_item(data_6d_item_idx).tx;...
                                                component(component_idx).frame(frame_idx).data(data_idx).data_6d_item(data_6d_item_idx).ty;...
                                                component(component_idx).frame(frame_idx).data(data_idx).data_6d_item(data_6d_item_idx).tz;];

                                            if(~isempty(t)&~isempty(q))

                                                R=double(cat(1,cat(2,M,t(:)),[0 0 0 1]));
    
                                                nav_camera_graphic_update(app,R,data_6d_item_idx);
                                            end;
                                        end;
                                    end;
                                end;
                            end;
                        elseif(strcmp(lower(app.CommandDropDown.Value),'bx')&~isempty(decode_obj))
                            component=decode_obj;


                            for component_idx=1:length(component)
                                if(~isnan(component(component_idx).q0))
                                    q=[ component(component_idx).q0;...
                                        component(component_idx).qx;...
                                        component(component_idx).qy;...
                                        component(component_idx).qz;];

                                    t=[ component(component_idx).tx;...
                                        component(component_idx).ty;...
                                        component(component_idx).tz;];

                                    if(~isempty(q)&~isempty(t))
                                        [M, euler]=nav_quat_rot(q);

                                        R=double(cat(1,cat(2,M,t(:)),[0 0 0 1]));

                                        nav_camera_graphic_update(app,R,component_idx);
                                    end;
                                end;
                            end;
                        end;


                        if(strcmp(lower(app.CommandDropDown.Value),'phrq')&~isempty(decode_obj))
                            app.nav_camera.port_handel=decode_obj;

                        end;

                    end;
                catch
                end;

%                pause(0.001); %to allow for reading 'repeat' switch
                pause(0.01); %to allow for reading 'repeat' switch

                if(strcmp(app.nav_camera.flag_repeat,'Off'))
                    break
                end;
            end;
        end

        % Value changed function: repeatSwitch
        function repeatSwitchValueChanged(app, event)
            value = app.repeatSwitch.Value;
            

            app.nav_camera.flag_repeat=value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                if(~app.nav_camera.flag_connected)
                    app.nav_camera.tcpip=tcpip(app.IPEditField.Value,str2num(app.PortEditField.Value));

                    fopen(app.nav_camera.tcpip);

                    app.Lamp.Color='g';

                    app.CommandDropDown.Enable='On';
                    app.CommandEditField.Enable='On';
                    app.SendButton.Enable='On';
                    app.repeatSwitch.Enable='On';
                    app.HEXTextArea.Enable='On';
                    app.infoTextArea.Enable='On';
                    app.UIAxes.Visible='On';


                    app.nav_camera.flag_connected=1;

                    app.ConnectButton.Text='Disconnect';

                else

                    fclose(app.nav_camera.tcpip);
                    try
                    for idx=1:length(app.nav_camera.dot_h)
                        %if(isvalid(app.nav_camera.dot_h(idx)))
                            delete(app.nav_camera.dot_h(idx));
                        %end;
                    end;
                    catch
                    end;
                    app.nav_camera.dot_h=[];

                    clear app.UIAxes;

                    app.Lamp.Color='r';

                    app.CommandDropDown.Enable='Off';
                    app.CommandEditField.Enable='Off';
                    app.SendButton.Enable='Off';
                    app.repeatSwitch.Enable='Off';
                    app.HEXTextArea.Enable='Off';
                    app.infoTextArea.Enable='Off';
                    app.UIAxes.Visible='Off';


                    app.nav_camera.flag_connected=0;

                    app.ConnectButton.Text='Connect';
                end;

            catch
            end;
        end

        % Value changed function: CommandDropDown
        function CommandDropDownValueChanged(app, event)
            value = app.CommandDropDown.Value;
            %default paaram for various commands
            switch lower(value)
                case 'init'
                    app.CommandEditField.Value='';
                case 'bx'
                    app.CommandEditField.Value='';
                case 'bx2'
                    app.CommandEditField.Value='--6d=tools --1d=none';
                case 'get'
                    app.CommandEditField.Value='';
                case 'phrq'
                    app.CommandEditField.Value='*********1****';
                case 'pinit'
                    app.CommandEditField.Value='01'; %first port handel 01 to be initialized....
                case 'pvwr'
                    app.CommandEditField.Value=''; %more complicated in providing tool definition data...
                case 'pena'
                    app.CommandEditField.Value='01D'; %<---set the first port handel '01' as a dynamic marker (D)....
                case 'phsr'
                    app.CommandEditField.Value='00'; %<---report all port handels
                case 'phinf'
                    app.CommandEditField.Value='01'; %<---the first port handel
                case 'beep'
                    app.CommandEditField.Value='1';
                case 'tstart'
                    app.CommandEditField.Value='';
                case 'tstop'
                    app.CommandEditField.Value='';
                case 'set'
                    app.CommandEditField.Value='';
                case 'tx'
                    app.CommandEditField.Value='';
            end;
        end

        % Button pushed function: BrainButton
        function BrainButtonPushed(app, event)
            if(~isfield(app.nav_camera,'etc_render_fsbrain'))
                etc_render_fsbrain_init;

                global etc_render_fsbrain;

                app.nav_camera.etc_render_fsbrain=etc_render_fsbrain;
            end;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.nav_camera_terminalUIFigure = uifigure('Visible', 'off');
            app.nav_camera_terminalUIFigure.Name = 'nav_camera_terminal';
            app.UIAxes = uiaxes(app.nav_camera_terminalUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.nav_camera_terminalUIFigure);
            app.HEXTextAreaLabel = uilabel(app.Panel_2);
            app.HEXTextAreaLabel.Text = 'HEX';
            app.HEXTextArea = uitextarea(app.Panel_2);
            app.infoTextAreaLabel = uilabel(app.Panel_2);
            app.infoTextAreaLabel.Text = 'info';
            app.infoTextArea = uitextarea(app.Panel_2);
            app.Panel = uipanel(app.nav_camera_terminalUIFigure);
            app.ConnectButton = uibutton(app.Panel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LampLabel = uilabel(app.Panel);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.Panel);
            app.SendButton = uibutton(app.nav_camera_terminalUIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.repeatSwitchLabel = uilabel(app.nav_camera_terminalUIFigure);
            app.repeatSwitchLabel.Text = 'repeat';
            app.repeatSwitch = uiswitch(app.nav_camera_terminalUIFigure, 'rocker');
            app.repeatSwitch.ValueChangedFcn = createCallbackFcn(app, @repeatSwitchValueChanged, true);
            app.IPEditFieldLabel = uilabel(app.nav_camera_terminalUIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.nav_camera_terminalUIFigure, 'text');
            app.IPEditField.Value = 'P9-10219.local';
            app.PortEditFieldLabel = uilabel(app.nav_camera_terminalUIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.nav_camera_terminalUIFigure, 'text');
            app.PortEditField.Value = '8765';
            app.CommandDropDownLabel = uilabel(app.nav_camera_terminalUIFigure);
            app.CommandDropDownLabel.Text = 'Command';
            app.CommandDropDown = uidropdown(app.nav_camera_terminalUIFigure);
            app.CommandDropDown.Items = {'INIT', 'BEEP', 'BX', 'BX2', 'GET', 'PHINF', 'PHSR', 'PHRQ', 'PVWR', 'PENA', 'PINIT', 'SET', 'TSTART', 'TSTOP', 'TX'};
            app.CommandDropDown.ValueChangedFcn = createCallbackFcn(app, @CommandDropDownValueChanged, true);
            app.CommandDropDown.Value = 'BX2';
            app.Label = uilabel(app.nav_camera_terminalUIFigure);
            app.Label.Text = '';
            app.CommandEditField = uieditfield(app.nav_camera_terminalUIFigure, 'text');
            app.CommandEditField.Value = '--6d=tools --1d=none';
            app.BrainButton = uibutton(app.nav_camera_terminalUIFigure, 'push');
            app.BrainButton.ButtonPushedFcn = createCallbackFcn(app, @BrainButtonPushed, true);
            app.BrainButton.Text = 'Brain';
            % Show the figure after all components are created
            app.nav_camera_terminalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nav_camera_terminal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.nav_camera_terminalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.nav_camera_terminalUIFigure)
        end
    end
end